classdef battlshup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        DisplayWin           matlab.ui.control.TextArea
        PlayerTurnLabel      matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        TurnIndicator        matlab.ui.control.Lamp
        ExitButton           matlab.ui.control.StateButton
        PlayAgainButton      matlab.ui.control.StateButton
        Lamp_42              matlab.ui.control.Lamp
        Lamp_41              matlab.ui.control.Lamp
        Lamp_40              matlab.ui.control.Lamp
        Lamp_39              matlab.ui.control.Lamp
        Lamp_38              matlab.ui.control.Lamp
        Lamp_37              matlab.ui.control.Lamp
        Lamp_36              matlab.ui.control.Lamp
        Lamp_35              matlab.ui.control.Lamp
        Lamp_34              matlab.ui.control.Lamp
        Lamp_33              matlab.ui.control.Lamp
        Lamp_32              matlab.ui.control.Lamp
        Lamp_31              matlab.ui.control.Lamp
        Lamp_30              matlab.ui.control.Lamp
        Lamp_29              matlab.ui.control.Lamp
        Lamp_28              matlab.ui.control.Lamp
        Lamp_27              matlab.ui.control.Lamp
        Lamp_26              matlab.ui.control.Lamp
        Lamp_25              matlab.ui.control.Lamp
        Lamp_24              matlab.ui.control.Lamp
        Lamp_23              matlab.ui.control.Lamp
        Lamp_22              matlab.ui.control.Lamp
        Lamp_21              matlab.ui.control.Lamp
        Lamp_20              matlab.ui.control.Lamp
        Lamp_19              matlab.ui.control.Lamp
        Lamp_18              matlab.ui.control.Lamp
        Lamp_17              matlab.ui.control.Lamp
        Lamp_16              matlab.ui.control.Lamp
        Lamp_15              matlab.ui.control.Lamp
        Lamp_14              matlab.ui.control.Lamp
        Lamp_13              matlab.ui.control.Lamp
        Lamp_12              matlab.ui.control.Lamp
        Lamp_11              matlab.ui.control.Lamp
        Lamp_10              matlab.ui.control.Lamp
        Lamp_9               matlab.ui.control.Lamp
        Lamp_8               matlab.ui.control.Lamp
        Lamp_7               matlab.ui.control.Lamp
        Lamp_6               matlab.ui.control.Lamp
        Lamp_5               matlab.ui.control.Lamp
        Lamp_4               matlab.ui.control.Lamp
        Lamp_3               matlab.ui.control.Lamp
        Lamp_2               matlab.ui.control.Lamp
        Lamp                 matlab.ui.control.Lamp
        InsertPieceButton_7  matlab.ui.control.Button
        InsertPieceButton_6  matlab.ui.control.Button
        InsertPieceButton_5  matlab.ui.control.Button
        InsertPieceButton_4  matlab.ui.control.Button
        InsertPieceButton_3  matlab.ui.control.Button
        InsertPieceButton_2  matlab.ui.control.Button
        InsertPieceButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        % Creates list color, 'w' is default value
        % Purpose of the list is to check for a 4 in a row of the same
        % color
        color = ['w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w', 'w'];
        buttonPressed = 0;
        playerTurn = 0;
        playerWon = false;
        pieceLock = false;
        clearingBoard = false;
        resettingGame = false;
    end
    
    methods (Access = public)

        
        function PlacePiece(app)
            
            % checks if a player has won yet, if they have, this function
            % won't execute
            if app.clearingBoard == false
                % If a player won, playerWon is true and the code below
                % won't execute
                if app.playerWon == false
                    % If a piece is falling, pieceLock is true and the code
                    % below won't execute
                    if app.pieceLock == false

                    % Identifies which column will be effected
                        if app.buttonPressed == 1
                            colorAssigner=6;
                            lampList = [app.Lamp, app.Lamp_2, app.Lamp_3 ,app.Lamp_4, app.Lamp_5, app.Lamp_6];
                        elseif app.buttonPressed == 2
                            colorAssigner=12;
                            lampList = [app.Lamp_7, app.Lamp_8, app.Lamp_9 ,app.Lamp_10, app.Lamp_11, app.Lamp_12];
                        elseif app.buttonPressed == 3
                            colorAssigner=18;
                            lampList = [app.Lamp_13, app.Lamp_14, app.Lamp_15 ,app.Lamp_16, app.Lamp_17, app.Lamp_18];
                        elseif app.buttonPressed == 4
                            colorAssigner=24;
                            lampList = [app.Lamp_19, app.Lamp_20, app.Lamp_21 ,app.Lamp_22, app.Lamp_23, app.Lamp_24];
                        elseif app.buttonPressed == 5
                            colorAssigner=30;
                            lampList = [app.Lamp_25, app.Lamp_26, app.Lamp_27 ,app.Lamp_28, app.Lamp_29, app.Lamp_30];
                        elseif app.buttonPressed == 6
                            colorAssigner=36;
                            lampList = [app.Lamp_31, app.Lamp_32, app.Lamp_33 ,app.Lamp_34, app.Lamp_35, app.Lamp_36];
                        elseif app.buttonPressed == 7
                            colorAssigner=42;
                            lampList = [app.Lamp_37, app.Lamp_38, app.Lamp_39 ,app.Lamp_40, app.Lamp_41, app.Lamp_42];
                        end
            
                        i = 6;
                        % Checks every spot in column until it finds a lamp
                        % that is enabled
                        while i>=1 && i<7
                            if lampList(i).Enable == 'off'
                                if app.playerTurn == 0
                                    app.playerTurn = 1;
                                    PieceFallingAnimation(app,lampList)
                                    lampList(i).Enable = 'on';
                                    lampList(i).Color = 'r';
                                    app.color(colorAssigner) = 'r';
                                    app.TurnIndicator.Color = 'k';
                                elseif app.playerTurn == 1
                                    app.playerTurn = 0;
                                    PieceFallingAnimation(app,lampList)
                                    lampList(i).Enable = 'on';
                                    lampList(i).Color = 'k';
                                    app.color(colorAssigner) = 'k';
                                    app.TurnIndicator.Color = 'r';
                                end
                                CheckForWin(app)
                                i=8;
                            end
                            colorAssigner=colorAssigner-1;
                            i=i-1;
                        end
                            if i==0
                                msgbox("Column is already filled, try again");
                            end
    
                    end
                end
            end
        end


        function FlashWin(app, winColorLocation, typeOfWin, bigListFlash)

            app.resettingGame = false;
            winColor = app.color(winColorLocation);
            pieceFlash = 1;

            DisplayWinner(app, winColor);

            while app.resettingGame == false
                for m=0:3
                    if pieceFlash == 1
                        bigListFlash(winColorLocation+(typeOfWin*m)).Color = 'w';
                        light = -1;
                    elseif pieceFlash == 0
                        bigListFlash(winColorLocation+(typeOfWin*m)).Color = winColor;
                        light = 1;
                    end
                end
                pieceFlash = pieceFlash + light;
                pause(.5);
            end
        end


        function DisplayWinner(app, winningColor)

            app.DisplayWin.Visible = 'on';

            if winningColor == 'r'
                app.DisplayWin.Value = "Red Wins!";
            elseif winningColor == 'k'
                app.DisplayWin.Value = "Black Wins!";
            end

        end


        function CheckForWin(app)

            bigList = [app.Lamp, app.Lamp_2, app.Lamp_3 ,app.Lamp_4, app.Lamp_5, app.Lamp_6,app.Lamp_7, app.Lamp_8, app.Lamp_9 ,app.Lamp_10, app.Lamp_11, app.Lamp_12,app.Lamp_13, app.Lamp_14, app.Lamp_15 ,app.Lamp_16, app.Lamp_17, app.Lamp_18,app.Lamp_19, app.Lamp_20, app.Lamp_21 ,app.Lamp_22, app.Lamp_23, app.Lamp_24,app.Lamp_25, app.Lamp_26, app.Lamp_27 ,app.Lamp_28, app.Lamp_29, app.Lamp_30,app.Lamp_31, app.Lamp_32, app.Lamp_33 ,app.Lamp_34, app.Lamp_35, app.Lamp_36,app.Lamp_37, app.Lamp_38, app.Lamp_39 ,app.Lamp_40, app.Lamp_41, app.Lamp_42];
            for checkWin=1
                for i=1:39
                        % Check vertical win
                        if bigList(i).Enable=='on' && bigList(i+1).Enable=='on' && bigList(i+2).Enable=='on' && bigList(i+3).Enable=='on'
                            if (app.color(i)=='r' && app.color(i+1)=='r' && app.color(i+2)=='r' && app.color(i+3)=='r') || (app.color(i)=='k' && app.color(i+1)=='k' && app.color(i+2)=='k' && app.color(i+3)=='k')
                                app.playerWon = true;
                                FlashWin(app,i,1,bigList);
                            end
                        end
                    if i+18<=42
                        % Check horizontal win
                        if bigList(i).Enable=='on' && bigList(i+6).Enable=='on' && bigList(i+12).Enable=='on' && bigList(i+18).Enable=='on'
                            if (app.color(i)=='r' && app.color(i+6)=='r' && app.color(i+12)=='r' && app.color(i+18)=='r') || (app.color(i)=='k' && app.color(i+6)=='k' && app.color(i+12)=='k' && app.color(i+18)=='k')                
                                app.playerWon = true;
                                FlashWin(app,i,6,bigList);
                            end
                        end
                    end
                    if i+15<=42
                        % Check diagonal win up
                        if bigList(i).Enable=='on' && bigList(i+5).Enable=='on' && bigList(i+10).Enable=='on' && bigList(i+15).Enable=='on'
                            if (app.color(i)=='r' && app.color(i+5)=='r' && app.color(i+10)=='r' && app.color(i+15)=='r') || (app.color(i)=='k' && app.color(i+5)=='k' && app.color(i+10)=='k' && app.color(i+15)=='k')
                                app.playerWon = true;
                                FlashWin(app,i,5,bigList);
                            end
                        end
                    end
                    if i+21<=42
                        % Check diagonal win down
                        if bigList(i).Enable=='on' && bigList(i+7).Enable=='on' && bigList(i+14).Enable=='on' && bigList(i+21).Enable=='on'
                            if (app.color(i)=='r' && app.color(i+7)=='r' && app.color(i+14)=='r' && app.color(i+21)=='r') || (app.color(i)=='k' && app.color(i+7)=='k' && app.color(i+14)=='k' && app.color(i+21)=='k')
                                app.playerWon = true;
                                FlashWin(app,i,7,bigList);
                            end
                        end
                    end
                end
            end
        end
        
        function PieceFallingAnimation(app, lampListFunction)
            
            % Locks the insert button so no piece can fall while another is
            % falling
            app.pieceLock = true;

            for i=1:6
                % Checks 
                if lampListFunction(i).Enable == 'off'
                    ChangeColor(app,lampListFunction,i)
                    lampListFunction(i).Enable = 'on';

                    % Adds physics (exponential speed acceleration)
                    pause(.098-(i/50));
                    lampListFunction(i).Enable = 'off';
                    lampListFunction(i).Color = 'w';
                end
            end
            app.pieceLock = false;
        end

        function ChangeColor(app,lampListColor,colorChange)

            % Changes color of the lamp depending on the players turn
            if app.playerTurn == 1
                lampListColor(colorChange).Color = 'r';
            elseif app.playerTurn == 0
                lampListColor(colorChange).Color = 'k';
            end
            
        end


        function ColorKeeper(app,biggyList,n)
            
            % Changes lamp color to the lamp on top of it
            for i=6:-1:2
                app.color(i+n)=app.color(i+n-1);
                biggyList(i+n).Color = app.color(i+n);

                % Checks to make sure the top piece isn't white, if it is
                % it immediately turns it off
                if app.color(i+n) == 'w'
                    biggyList(i+n).Enable = 'off';
                end
            end

            % Makes top row white no matter what
            app.color(1+n) = 'w';
            biggyList(1+n).Color = app.color(1+n);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertPieceButton
        function InsertPieceButtonPushed(app, event)
            app.buttonPressed = 1;
            PlacePiece(app);
        end

        % Button pushed function: InsertPieceButton_2
        function InsertPieceButton_2Pushed(app, event)
            app.buttonPressed = 2;
            PlacePiece(app);
        end

        % Button pushed function: InsertPieceButton_3
        function InsertPieceButton_3Pushed(app, event)
            app.buttonPressed = 3;
            PlacePiece(app);
        end

        % Button pushed function: InsertPieceButton_4
        function InsertPieceButton_4Pushed(app, event)
            app.buttonPressed = 4;
            PlacePiece(app);
        end

        % Button pushed function: InsertPieceButton_5
        function InsertPieceButton_5Pushed(app, event)
            app.buttonPressed = 5;
            PlacePiece(app);
        end

        % Button pushed function: InsertPieceButton_6
        function InsertPieceButton_6Pushed(app, event)
            app.buttonPressed = 6;
            PlacePiece(app);
        end

        % Button pushed function: InsertPieceButton_7
        function InsertPieceButton_7Pushed(app, event)
            app.buttonPressed = 7;
            PlacePiece(app);
        end

        % Value changed function: ExitButton
        function ExitConnectFour(app, event)
            close all force;
        end

        % Value changed function: PlayAgainButton
        function PlayAgain(app, event)

            bigListReset = [app.Lamp, app.Lamp_2, app.Lamp_3, app.Lamp_4, app.Lamp_5, app.Lamp_6, app.Lamp_7, app.Lamp_8, app.Lamp_9, app.Lamp_10, app.Lamp_11, app.Lamp_12,app.Lamp_13, app.Lamp_14, app.Lamp_15 ,app.Lamp_16, app.Lamp_17, app.Lamp_18,app.Lamp_19, app.Lamp_20, app.Lamp_21 ,app.Lamp_22, app.Lamp_23, app.Lamp_24,app.Lamp_25, app.Lamp_26, app.Lamp_27 ,app.Lamp_28, app.Lamp_29, app.Lamp_30,app.Lamp_31, app.Lamp_32, app.Lamp_33 ,app.Lamp_34, app.Lamp_35, app.Lamp_36,app.Lamp_37, app.Lamp_38, app.Lamp_39 ,app.Lamp_40, app.Lamp_41, app.Lamp_42];
            % Resets if a player won
            app.playerWon = false;
            % Locks the board from any pieces being played
            app.clearingBoard = true;
            app.resettingGame = true;
            app.DisplayWin.Visible = 'off';

            for i=1:6
                for m=0:6:36
                        ColorKeeper(app,bigListReset,m);
                        bigListReset(i+m).Enable = 'off';
                        bigListReset(i+m).Color = 'w';
                    
                end
                % Adds physics (exponential speed acceleration)
                pause(.098-(i/50))
            end

            % Allows placement of pieces again
            app.clearingBoard = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InsertPieceButton = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButtonPushed, true);
            app.InsertPieceButton.Text = {'Insert'; 'Piece'};
            app.InsertPieceButton_2 = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton_2.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButton_2Pushed, true);
            app.InsertPieceButton_2.Text = {'Insert'; 'Piece'};
            app.InsertPieceButton_3 = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton_3.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButton_3Pushed, true);
            app.InsertPieceButton_3.Text = {'Insert'; 'Piece'};
            app.InsertPieceButton_4 = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton_4.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButton_4Pushed, true);
            app.InsertPieceButton_4.Text = {'Insert'; 'Piece'};
            app.InsertPieceButton_5 = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton_5.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButton_5Pushed, true);
            app.InsertPieceButton_5.Text = {'Insert'; 'Piece'};
            app.InsertPieceButton_6 = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton_6.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButton_6Pushed, true);
            app.InsertPieceButton_6.Text = {'Insert'; 'Piece'};
            app.InsertPieceButton_7 = uibutton(app.GridLayout, 'push');
            app.InsertPieceButton_7.ButtonPushedFcn = createCallbackFcn(app, @InsertPieceButton_7Pushed, true);
            app.InsertPieceButton_7.Text = {'Insert'; 'Piece'};
            app.Lamp = uilamp(app.GridLayout);
            app.Lamp.Enable = 'off';
            app.Lamp_2 = uilamp(app.GridLayout);
            app.Lamp_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.GridLayout);
            app.Lamp_3.Enable = 'off';
            app.Lamp_4 = uilamp(app.GridLayout);
            app.Lamp_4.Enable = 'off';
            app.Lamp_5 = uilamp(app.GridLayout);
            app.Lamp_5.Enable = 'off';
            app.Lamp_6 = uilamp(app.GridLayout);
            app.Lamp_6.Enable = 'off';
            app.Lamp_7 = uilamp(app.GridLayout);
            app.Lamp_7.Enable = 'off';
            app.Lamp_8 = uilamp(app.GridLayout);
            app.Lamp_8.Enable = 'off';
            app.Lamp_9 = uilamp(app.GridLayout);
            app.Lamp_9.Enable = 'off';
            app.Lamp_10 = uilamp(app.GridLayout);
            app.Lamp_10.Enable = 'off';
            app.Lamp_11 = uilamp(app.GridLayout);
            app.Lamp_11.Enable = 'off';
            app.Lamp_12 = uilamp(app.GridLayout);
            app.Lamp_12.Enable = 'off';
            app.Lamp_13 = uilamp(app.GridLayout);
            app.Lamp_13.Enable = 'off';
            app.Lamp_14 = uilamp(app.GridLayout);
            app.Lamp_14.Enable = 'off';
            app.Lamp_15 = uilamp(app.GridLayout);
            app.Lamp_15.Enable = 'off';
            app.Lamp_16 = uilamp(app.GridLayout);
            app.Lamp_16.Enable = 'off';
            app.Lamp_17 = uilamp(app.GridLayout);
            app.Lamp_17.Enable = 'off';
            app.Lamp_18 = uilamp(app.GridLayout);
            app.Lamp_18.Enable = 'off';
            app.Lamp_19 = uilamp(app.GridLayout);
            app.Lamp_19.Enable = 'off';
            app.Lamp_20 = uilamp(app.GridLayout);
            app.Lamp_20.Enable = 'off';
            app.Lamp_21 = uilamp(app.GridLayout);
            app.Lamp_21.Enable = 'off';
            app.Lamp_22 = uilamp(app.GridLayout);
            app.Lamp_22.Enable = 'off';
            app.Lamp_23 = uilamp(app.GridLayout);
            app.Lamp_23.Enable = 'off';
            app.Lamp_24 = uilamp(app.GridLayout);
            app.Lamp_24.Enable = 'off';
            app.Lamp_25 = uilamp(app.GridLayout);
            app.Lamp_25.Enable = 'off';
            app.Lamp_26 = uilamp(app.GridLayout);
            app.Lamp_26.Enable = 'off';
            app.Lamp_27 = uilamp(app.GridLayout);
            app.Lamp_27.Enable = 'off';
            app.Lamp_28 = uilamp(app.GridLayout);
            app.Lamp_28.Enable = 'off';
            app.Lamp_29 = uilamp(app.GridLayout);
            app.Lamp_29.Enable = 'off';
            app.Lamp_30 = uilamp(app.GridLayout);
            app.Lamp_30.Enable = 'off';
            app.Lamp_31 = uilamp(app.GridLayout);
            app.Lamp_31.Enable = 'off';
            app.Lamp_32 = uilamp(app.GridLayout);
            app.Lamp_32.Enable = 'off';
            app.Lamp_33 = uilamp(app.GridLayout);
            app.Lamp_33.Enable = 'off';
            app.Lamp_34 = uilamp(app.GridLayout);
            app.Lamp_34.Enable = 'off';
            app.Lamp_35 = uilamp(app.GridLayout);
            app.Lamp_35.Enable = 'off';
            app.Lamp_36 = uilamp(app.GridLayout);
            app.Lamp_36.Enable = 'off';
            app.Lamp_37 = uilamp(app.GridLayout);
            app.Lamp_37.Enable = 'off';
            app.Lamp_38 = uilamp(app.GridLayout);
            app.Lamp_38.Enable = 'off';
            app.Lamp_39 = uilamp(app.GridLayout);
            app.Lamp_39.Enable = 'off';
            app.Lamp_40 = uilamp(app.GridLayout);
            app.Lamp_40.Enable = 'off';
            app.Lamp_41 = uilamp(app.GridLayout);
            app.Lamp_41.Enable = 'off';
            app.Lamp_42 = uilamp(app.GridLayout);
            app.Lamp_42.Enable = 'off';
            app.PlayAgainButton = uibutton(app.GridLayout, 'state');
            app.PlayAgainButton.ValueChangedFcn = createCallbackFcn(app, @PlayAgain, true);
            app.PlayAgainButton.Text = {'Play'; 'Again'};
            app.ExitButton = uibutton(app.GridLayout, 'state');
            app.ExitButton.ValueChangedFcn = createCallbackFcn(app, @ExitConnectFour, true);
            app.ExitButton.Text = 'Exit';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TurnIndicator = uilamp(app.GridLayout2);
            app.PlayerTurnLabel = uilabel(app.GridLayout);
            app.PlayerTurnLabel.Text = {'Player'; 'Turn'};
            app.DisplayWin = uitextarea(app.GridLayout);
            app.DisplayWin.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = battlshup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end